classdef FoodTracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        ProgressTowardsGoalsLabel     matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        SliderLabel                   matlab.ui.control.Label
        NameofFoodEditField           matlab.ui.control.EditField
        NameofFoodEditFieldLabel      matlab.ui.control.Label
        TotalCaloriesEditField        matlab.ui.control.NumericEditField
        TotalCaloriesEditFieldLabel   matlab.ui.control.Label
        PleaseenterthefollowinginformationLabel  matlab.ui.control.Label
        CaloriesofFoodEditField       matlab.ui.control.NumericEditField
        CaloriesofFoodEditFieldLabel  matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        FatEditField                  matlab.ui.control.NumericEditField
        FatEditFieldLabel             matlab.ui.control.Label
        CarbohydratesEditField        matlab.ui.control.NumericEditField
        CarbohydratesEditFieldLabel   matlab.ui.control.Label
        ProteinEditField              matlab.ui.control.NumericEditField
        ProteinEditFieldLabel         matlab.ui.control.Label
        FoodTrackerLabel              matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            load("userData.mat","userData")
            %Assign variables and store them in food struct
            foodData.name = app.NameofFoodEditField.Value;
            foodData.protein = app.ProteinEditField.Value;
            foodData.fat = app.FatEditField.Value;
            foodData.carbohydrates = app.CarbohydratesEditField.Value;
            
            %Update total calories eaten
            userData.totalCaloriesEaten = userData.totalCaloriesEaten + calcMacros(foodData);
            
            %Calculate calories of food and total calories for person
            app.CaloriesofFoodEditField.Value = calcMacros(foodData);
            app.TotalCaloriesEditField.Value = userData.totalCaloriesEaten;
            app.Slider.Value = userData.totalCaloriesEaten;
            
            %Save foods and total calories for the day
            save("foodData.mat", "foodData");
            save("userData.mat","userData");

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FoodTrackerLabel = uilabel(app.UIFigure);
            app.FoodTrackerLabel.Text = 'Food Tracker';
            app.ProteinEditFieldLabel = uilabel(app.UIFigure);
            app.ProteinEditFieldLabel.Text = 'Protein';
            app.ProteinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProteinEditField.AllowEmpty = 'on';
            app.ProteinEditField.Value = [];
            app.CarbohydratesEditFieldLabel = uilabel(app.UIFigure);
            app.CarbohydratesEditFieldLabel.Text = 'Carbohydrates';
            app.CarbohydratesEditField = uieditfield(app.UIFigure, 'numeric');
            app.CarbohydratesEditField.AllowEmpty = 'on';
            app.CarbohydratesEditField.Value = [];
            app.FatEditFieldLabel = uilabel(app.UIFigure);
            app.FatEditFieldLabel.Text = 'Fat';
            app.FatEditField = uieditfield(app.UIFigure, 'numeric');
            app.FatEditField.AllowEmpty = 'on';
            app.FatEditField.Value = [];
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CaloriesofFoodEditFieldLabel = uilabel(app.UIFigure);
            app.CaloriesofFoodEditFieldLabel.Text = 'Calories of Food';
            app.CaloriesofFoodEditField = uieditfield(app.UIFigure, 'numeric');
            app.CaloriesofFoodEditField.AllowEmpty = 'on';
            app.CaloriesofFoodEditField.Editable = 'off';
            app.CaloriesofFoodEditField.Value = [];
            app.PleaseenterthefollowinginformationLabel = uilabel(app.UIFigure);
            app.PleaseenterthefollowinginformationLabel.Text = 'Please enter the following information';
            app.TotalCaloriesEditFieldLabel = uilabel(app.UIFigure);
            app.TotalCaloriesEditFieldLabel.Text = 'Total Calories';
            app.TotalCaloriesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalCaloriesEditField.AllowEmpty = 'on';
            app.TotalCaloriesEditField.Editable = 'off';
            app.TotalCaloriesEditField.Value = [];
            app.NameofFoodEditFieldLabel = uilabel(app.UIFigure);
            app.NameofFoodEditFieldLabel.Text = 'Name of Food';
            app.NameofFoodEditField = uieditfield(app.UIFigure, 'text');
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Enable = 'off';
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 3000];
            app.Slider.Enable = 'off';
            app.ProgressTowardsGoalsLabel = uilabel(app.UIFigure);
            app.ProgressTowardsGoalsLabel.Text = 'Progress Towards Goals';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FoodTracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end