classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Tab4                          matlab.ui.container.Tab
        FitnessTrackerLabel           matlab.ui.control.Label
        Tab                           matlab.ui.container.Tab
        TotalCaloriesBurnedEditField  matlab.ui.control.NumericEditField
        ActivityLevelDropDown         matlab.ui.control.DropDown
        ActivityLevelDropDownLabel    matlab.ui.control.Label
        GenderDropDown                matlab.ui.control.DropDown
        GenderDropDownLabel           matlab.ui.control.Label
        AgeyearsEditField             matlab.ui.control.NumericEditField
        AgeyearsEditFieldLabel        matlab.ui.control.Label
        HeightcmEditField             matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel        matlab.ui.control.Label
        WeightkgEditField             matlab.ui.control.NumericEditField
        WeightkgEditFieldLabel        matlab.ui.control.Label
        TotalCaloriesBurnedEditFieldLabel  matlab.ui.control.Label
        CalculateButton               matlab.ui.control.StateButton
        PleaseenterthefollowinginformationLabel  matlab.ui.control.Label
        Tab2                          matlab.ui.container.Tab
        Slider                        matlab.ui.control.Slider
        ProteinEditField              matlab.ui.control.NumericEditField
        ProteinEditFieldLabel         matlab.ui.control.Label
        NameofFoodEditField           matlab.ui.control.EditField
        NameofFoodEditFieldLabel      matlab.ui.control.Label
        TotalCaloriesEditField        matlab.ui.control.NumericEditField
        TotalCaloriesEditFieldLabel   matlab.ui.control.Label
        CaloriesofFoodEditField       matlab.ui.control.NumericEditField
        CaloriesofFoodEditFieldLabel  matlab.ui.control.Label
        FatEditField                  matlab.ui.control.NumericEditField
        FatEditFieldLabel             matlab.ui.control.Label
        CarbohydratesEditField        matlab.ui.control.NumericEditField
        CarbohydratesEditFieldLabel   matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ProgressTowardsGoalsLabel     matlab.ui.control.Label
        SliderLabel                   matlab.ui.control.Label
        PleaseenterthefollowinginformationLabel_2  matlab.ui.control.Label
        CalculateButton_2             matlab.ui.control.Button
        Tab3                          matlab.ui.container.Tab
        TrainModelButton              matlab.ui.control.Button
        ImportButton                  matlab.ui.control.Button
        FilenameEditField             matlab.ui.control.EditField
        FilenameEditFieldLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CalculateButton
        function CalculateButtonValueChanged(app, event)
            %Assign variables and store them into struct
            userData.weight = app.WeightkgEditField.Value;
            userData.height = app.HeightcmEditField.Value;
            userData.age = app.AgeyearsEditField.Value;
            userData.gender = app.GenderDropDown.Value;
            userData.activityLvl = app.ActivityLevelDropDown.Value;
            userData.totalCaloriesEaten = 0;

            %Call function to calculate total calories burned
            app.TotalCaloriesBurnedEditField.Value = calculateTDEE(userData);

            %Save value to struct
            userData.TDEE = app.TotalCaloriesBurnedEditField.Value;

            %Save struct as file to use later
            save('userData.mat', 'userData');
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            load("userData.mat","userData")
            %Assign variables and store them in food struct
            foodData.name = app.NameofFoodEditField.Value;
            foodData.protein = app.ProteinEditField.Value;
            foodData.fat = app.FatEditField.Value;
            foodData.carbohydrates = app.CarbohydratesEditField.Value;
            
            %Update total calories eaten
            userData.totalCaloriesEaten = userData.totalCaloriesEaten + calcMacros(foodData);
            
            %Calculate calories of food and total calories for person
            app.CaloriesofFoodEditField.Value = calcMacros(foodData);
            app.TotalCaloriesEditField.Value = userData.totalCaloriesEaten;
            app.Slider.Value = userData.totalCaloriesEaten;
            
            %Save foods and total calories for the day
            save("foodData.mat", "foodData");
            save("userData.mat","userData");
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [filename,path] = uigetfile();
            app.FilenameEditField.Value = filename;
            figure(app.UIFigure);
            
            sourceFile = fullfile(path, filname);
            destFile = fullfile("matlab-mobile-fitness-tracker\Data", filename);
            copyfile(sourceFile, destFile);
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            value = app.FilenameEditField.Value;
            
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            %train model function
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.FitnessTrackerLabel = uilabel(app.Tab4);
            app.FitnessTrackerLabel.Text = 'Fitness Tracker';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.PleaseenterthefollowinginformationLabel = uilabel(app.Tab);
            app.PleaseenterthefollowinginformationLabel.Text = 'Please enter the following information';
            app.CalculateButton = uibutton(app.Tab, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonValueChanged, true);
            app.CalculateButton.Text = 'Calculate';
            app.TotalCaloriesBurnedEditFieldLabel = uilabel(app.Tab);
            app.TotalCaloriesBurnedEditFieldLabel.Text = 'Total Calories Burned';
            app.WeightkgEditFieldLabel = uilabel(app.Tab);
            app.WeightkgEditFieldLabel.Text = 'Weight (kg)';
            app.WeightkgEditField = uieditfield(app.Tab, 'numeric');
            app.HeightcmEditFieldLabel = uilabel(app.Tab);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.HeightcmEditField = uieditfield(app.Tab, 'numeric');
            app.AgeyearsEditFieldLabel = uilabel(app.Tab);
            app.AgeyearsEditFieldLabel.Text = 'Age (years)';
            app.AgeyearsEditField = uieditfield(app.Tab, 'numeric');
            app.GenderDropDownLabel = uilabel(app.Tab);
            app.GenderDropDownLabel.Text = 'Gender';
            app.GenderDropDown = uidropdown(app.Tab);
            app.GenderDropDown.Items = {'Male ', 'Female'};
            app.GenderDropDown.Value = 'Male ';
            app.ActivityLevelDropDownLabel = uilabel(app.Tab);
            app.ActivityLevelDropDownLabel.Text = 'Activity Level';
            app.ActivityLevelDropDown = uidropdown(app.Tab);
            app.ActivityLevelDropDown.Items = {'Sedentary (little to no exercise)', 'Lightly active (light exercise 1–3 days per week)', 'Moderately active (moderate exercise 3–5 days per week)', 'Very active (hard exercise 6–7 days per week)', 'Extra active (very hard exercise two or more times per day, training, or a physical job)'};
            app.ActivityLevelDropDown.Value = 'Sedentary (little to no exercise)';
            app.TotalCaloriesBurnedEditField = uieditfield(app.Tab, 'numeric');
            app.TotalCaloriesBurnedEditField.Editable = 'off';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.CalculateButton_2 = uibutton(app.Tab2, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.PleaseenterthefollowinginformationLabel_2 = uilabel(app.Tab2);
            app.PleaseenterthefollowinginformationLabel_2.Text = 'Please enter the following information';
            app.SliderLabel = uilabel(app.Tab2);
            app.SliderLabel.Enable = 'off';
            app.SliderLabel.Text = 'Slider';
            app.ProgressTowardsGoalsLabel = uilabel(app.Tab2);
            app.ProgressTowardsGoalsLabel.Text = 'Progress Towards Goals';
            app.Image = uiimage(app.Tab2);
            app.CarbohydratesEditFieldLabel = uilabel(app.Tab2);
            app.CarbohydratesEditFieldLabel.Text = 'Carbohydrates';
            app.CarbohydratesEditField = uieditfield(app.Tab2, 'numeric');
            app.CarbohydratesEditField.AllowEmpty = 'on';
            app.CarbohydratesEditField.Value = [];
            app.FatEditFieldLabel = uilabel(app.Tab2);
            app.FatEditFieldLabel.Text = 'Fat';
            app.FatEditField = uieditfield(app.Tab2, 'numeric');
            app.FatEditField.AllowEmpty = 'on';
            app.FatEditField.Value = [];
            app.CaloriesofFoodEditFieldLabel = uilabel(app.Tab2);
            app.CaloriesofFoodEditFieldLabel.Text = 'Calories of Food';
            app.CaloriesofFoodEditField = uieditfield(app.Tab2, 'numeric');
            app.CaloriesofFoodEditField.AllowEmpty = 'on';
            app.CaloriesofFoodEditField.Editable = 'off';
            app.CaloriesofFoodEditField.Value = [];
            app.TotalCaloriesEditFieldLabel = uilabel(app.Tab2);
            app.TotalCaloriesEditFieldLabel.Text = 'Total Calories';
            app.TotalCaloriesEditField = uieditfield(app.Tab2, 'numeric');
            app.TotalCaloriesEditField.AllowEmpty = 'on';
            app.TotalCaloriesEditField.Editable = 'off';
            app.TotalCaloriesEditField.Value = [];
            app.NameofFoodEditFieldLabel = uilabel(app.Tab2);
            app.NameofFoodEditFieldLabel.Text = 'Name of Food';
            app.NameofFoodEditField = uieditfield(app.Tab2, 'text');
            app.ProteinEditFieldLabel = uilabel(app.Tab2);
            app.ProteinEditFieldLabel.Text = 'Protein';
            app.ProteinEditField = uieditfield(app.Tab2, 'numeric');
            app.ProteinEditField.AllowEmpty = 'on';
            app.ProteinEditField.Value = [];
            app.Slider = uislider(app.Tab2);
            app.Slider.Limits = [0 3000];
            app.Slider.Enable = 'off';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.FilenameEditFieldLabel = uilabel(app.Tab3);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.Tab3, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.ImportButton = uibutton(app.Tab3, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.TrainModelButton = uibutton(app.Tab3, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Text = 'Train Model';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end